classdef Standa_rotation_control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MortgageCalculatorUIFigure  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        CurrentstepdegreesEditFieldLabel  matlab.ui.control.Label
        CurrentstepEditField        matlab.ui.control.NumericEditField
        GotopositionindegreesEditFieldLabel  matlab.ui.control.Label
        GotopositionEditField       matlab.ui.control.NumericEditField
        microstepEditFieldLabel     matlab.ui.control.Label
        microstepEditField          matlab.ui.control.NumericEditField
        GOButton                    matlab.ui.control.Button
        MonthlyPaymentEditField     matlab.ui.control.NumericEditField
        StatusEditField_2Label      matlab.ui.control.Label
        StatusEditField_2           matlab.ui.control.EditField
        Label                       matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Standa_dev_id % Standa device
    end
    
    methods (Access = private)
        
        function [pos, upos] = Standa_init(app)
            [~,maxArraySize]=computer;
            is64bit = maxArraySize > 2^31;
            if (ispc)
               	if (is64bit)
                 			disp('Using 64-bit version')
               	else
                 			disp('Using 32-bit version')
               	end
            elseif ismac
               	disp('Using mac version')
            elseif isunix
               	disp('Using unix version, check your compilers')
            end
            
            if not(libisloaded('libximc'))
                disp('Loading library')
              		if ispc
                 			addpath(fullfile(pwd,'../../ximc/win64/wrappers/matlab/'));
                 			if (is64bit)
                   					addpath(fullfile(pwd,'../../ximc/win64/'));
                   					[notfound,warnings] = loadlibrary('libximc.dll', @ximcm)
                 			else
                   					addpath(fullfile(pwd,'../../ximc/win32/'));
                   					[notfound, warnings] = loadlibrary('libximc.dll', 'ximcm.h', 'addheader', 'ximc.h')
                 			end
              		elseif ismac
                 			addpath(fullfile(pwd,'../../ximc/'));
                 			[notfound, warnings] = loadlibrary('libximc.framework/libximc', 'ximcm.h', 'mfilename', 'ximcm.m', 'includepath', 'libximc.framework/Versions/Current/Headers', 'addheader', 'ximc.h')
              		elseif isunix
                 			[notfound, warnings] = loadlibrary('libximc.so', 'ximcm.h', 'addheader', 'ximc.h')
              		end
            end
            
            % Set bindy (network) keyfile. Must be called before any call to "enumerate_devices" or "open_device" if you
            % wish to use network-attached controllers. Accepts both absolute and relative paths, relative paths are resolved
            % relative to the process working directory. If you do not need network devices then "set_bindy_key" is optional.
            calllib('libximc','set_bindy_key', '../../ximc/win32/keyfile.sqlite')
            
            probe_flags = 1 + 4; % ENUMERATE_PROBE and ENUMERATE_NETWORK flags used
            enum_hints = 'addr=192.168.1.1,172.16.2.3';
            % enum_hints = 'addr='; % Use this hint string for broadcast enumeration
            device_names = ximc_enumerate_devices_wrap(probe_flags, enum_hints);
            devices_count = size(device_names,2);
            if devices_count == 0
                disp('No devices found')
                return
            end
            for i=1:devices_count
                disp(['Found device: ', device_names{1,i}]);
            end
            device_name = device_names{1,1};
            state = ['Using device name ', device_name];
            app.StatusEditField_2.Value = state;
            
            app.Standa_dev_id = calllib('libximc','open_device', device_name);
            state =['Using device id ', num2str(app.Standa_dev_id)];
            app.StatusEditField_2.Value = state;
            
            serial = ximc_get_serial_number(app.Standa_dev_id);
            state = ['Serial ' mat2str(serial,100)];
            app.StatusEditField_2.Value = state;
            
            state = 'Set microstep mode to 256...';
            app.StatusEditField_2.Value = state;
            ximc_set_microstep_256(app.Standa_dev_id);
            
            % get current position in steps and microsteps
            state_s = ximc_get_status(app.Standa_dev_id);            
            pos = state_s.CurPosition;
            upos = state_s.uCurPosition;
        end
        
        function Standa_move(app, angle)
            % Move to position in degrees. Accurancy is 0.01 deg, as steps
            % are used. Usteps are not used
            angle_in_steps = fix(angle*1e2);
            %uangle_in_steps = fix((angle - angle_in_steps) * 2.56);
            result = calllib('libximc','command_move', app.Standa_dev_id, angle_in_steps, 0);
            if result ~= 0
                disp(['Command failed with code', num2str(result)]);
            end
            % waiting for stop
            result = calllib('libximc','command_wait_for_stop', app.Standa_dev_id, 100);
            if result ~= 0
                disp(['Command failed with code', num2str(result)]);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [start_position, start_uposition] = app.Standa_init;            
            app.CurrentstepEditField.Value = start_position*1e-2;
            app.microstepEditField.Value = start_uposition;          
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MortgageCalculatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {316, 316};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {571, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            new_position = app.GotopositionEditField.Value;
            app.Standa_move(new_position);
        end

        % Close request function: MortgageCalculatorUIFigure
        function MortgageCalculatorUIFigureCloseRequest(app, event)
            % closing Standa
            Standa_dev_id_ptr = libpointer('int32Ptr', app.Standa_dev_id);
            calllib('libximc','close_device', Standa_dev_id_ptr);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MortgageCalculatorUIFigure = uifigure('Visible', 'off');
            app.MortgageCalculatorUIFigure.Name = 'Mortgage Calculator';
            app.MortgageCalculatorUIFigure.CloseRequestFcn = createCallbackFcn(app, @MortgageCalculatorUIFigureCloseRequest, true);
            app.MortgageCalculatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MortgageCalculatorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CurrentstepdegreesEditFieldLabel = uilabel(app.LeftPanel);
            app.CurrentstepdegreesEditFieldLabel.Text = 'Current step (degrees)';
            app.CurrentstepEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CurrentstepEditField.Value = 300000;
            app.GotopositionindegreesEditFieldLabel = uilabel(app.LeftPanel);
            app.GotopositionindegreesEditFieldLabel.Text = 'Go to position (in degrees)';
            app.GotopositionEditField = uieditfield(app.LeftPanel, 'numeric');
            app.GotopositionEditField.Value = 4500;
            app.microstepEditFieldLabel = uilabel(app.LeftPanel);
            app.microstepEditFieldLabel.Text = 'microstep ';
            app.microstepEditField = uieditfield(app.LeftPanel, 'numeric');
            app.microstepEditField.ValueDisplayFormat = '%.0f';
            app.microstepEditField.Value = 30;
            app.GOButton = uibutton(app.LeftPanel, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO';
            app.MonthlyPaymentEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MonthlyPaymentEditField.ValueDisplayFormat = '%7.2f';
            app.MonthlyPaymentEditField.Editable = 'off';
            app.StatusEditField_2Label = uilabel(app.LeftPanel);
            app.StatusEditField_2Label.Text = 'Status';
            app.StatusEditField_2 = uieditfield(app.LeftPanel, 'text');
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '/256';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.MortgageCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Standa_rotation_control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MortgageCalculatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MortgageCalculatorUIFigure)
        end
    end
end